classdef yieldcalc < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        YieldCalculatorUIFigure     matlab.ui.Figure
        EnterDiscountRateEditField  matlab.ui.control.EditField
        MaturityDateEditField       matlab.ui.control.EditField
        SettleDateEditField         matlab.ui.control.EditField
        BondPriceLabel              matlab.ui.control.Label
        MoneyMarketYieldLabel       matlab.ui.control.Label
        BondEquivalentYieldLabel    matlab.ui.control.Label
        by                          matlab.ui.control.Label
        my                          matlab.ui.control.Label
        bey                         matlab.ui.control.Label
        ComputeButton               matlab.ui.control.Button
        DiscountRateLabel           matlab.ui.control.Label
        SettleDateLabel             matlab.ui.control.Label
        MaturityDateLabel           matlab.ui.control.Label
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.SettleDateEditField.Value = datestr(floor(now),'mm-dd-yyyy');
            app.by.Text = "";
            app.my.Text = "";
            app.bey.Text = "";
        end

        % Button pushed function: ComputeButton
        function ComputeButtonPushed(app, event)
            d = str2num(app.EnterDiscountRateEditField.Value); % get discount rate
            s = datenum(app.SettleDateEditField.Value); % get settle date
            m = datenum(app.MaturityDateEditField.Value); % get maturity date
            t2m = m-s; % calculate DTM
            p = 100 - d * (t2m/360); % calculate bond price
            app.by.Text = num2str(p,'%0.5f'); % set bond price label
            app.my.Text = num2str(((100 - d)/d)*(360/t2m)*100,'%0.5f'); % compute money market yield
            app.bey.Text = num2str(((100 - d)/d)*(365/t2m)*100,'%0.5f'); % compute bond equivalent yield
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.YieldCalculatorUIFigure = uifigure;
            app.YieldCalculatorUIFigure.Name = 'Yield Calculator';
            app.EnterDiscountRateEditField = uieditfield(app.YieldCalculatorUIFigure, 'text');
            app.MaturityDateEditField = uieditfield(app.YieldCalculatorUIFigure, 'text');
            app.SettleDateEditField = uieditfield(app.YieldCalculatorUIFigure, 'text');
            app.BondPriceLabel = uilabel(app.YieldCalculatorUIFigure);
            app.BondPriceLabel.Text = 'Bond Price:';
            app.MoneyMarketYieldLabel = uilabel(app.YieldCalculatorUIFigure);
            app.MoneyMarketYieldLabel.Text = 'Money Market Yield:';
            app.BondEquivalentYieldLabel = uilabel(app.YieldCalculatorUIFigure);
            app.BondEquivalentYieldLabel.Text = 'Bond Equivalent Yield:';
            app.by = uilabel(app.YieldCalculatorUIFigure);
            app.my = uilabel(app.YieldCalculatorUIFigure);
            app.bey = uilabel(app.YieldCalculatorUIFigure);
            app.ComputeButton = uibutton(app.YieldCalculatorUIFigure, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton.Text = 'Compute';
            app.DiscountRateLabel = uilabel(app.YieldCalculatorUIFigure);
            app.DiscountRateLabel.Text = 'Discount Rate:';
            app.SettleDateLabel = uilabel(app.YieldCalculatorUIFigure);
            app.SettleDateLabel.Text = 'Settle Date:';
            app.MaturityDateLabel = uilabel(app.YieldCalculatorUIFigure);
            app.MaturityDateLabel.Text = 'Maturity Date:';
        end
    end

    methods (Access = public)

        % Construct app
        function app = yieldcalc

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.YieldCalculatorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.YieldCalculatorUIFigure)
        end
    end
end